classdef AppVisual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        AnalysisTab                    matlab.ui.container.Tab
        PredictingPanel                matlab.ui.container.Panel
        FeedbackButton                 matlab.ui.control.Button
        ClassificationResultEditField  matlab.ui.control.EditField
        ClassificationResultEditFieldLabel  matlab.ui.control.Label
        PneumoniaProbabilityEditField  matlab.ui.control.EditField
        PneumoniaProbabilityEditFieldLabel  matlab.ui.control.Label
        ModelDropDown                  matlab.ui.control.DropDown
        ModelDropDownLabel             matlab.ui.control.Label
        PredictButton                  matlab.ui.control.Button
        OtherManagementPanel           matlab.ui.container.Panel
        ExitButton                     matlab.ui.control.Button
        ClearandCloseAllButton         matlab.ui.control.Button
        ChestXRayViewerPanel           matlab.ui.container.Panel
        FilenameEditField              matlab.ui.control.EditField
        FilenameLabel                  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        LoadingPanel                   matlab.ui.container.Panel
        LoadingChestXRayPictureButton  matlab.ui.control.Button
        LoadingChestXRayDICOMButton    matlab.ui.control.Button
        HeatMapGradCAMTab              matlab.ui.container.Tab
        ChestXRaywithGradCAMPanel      matlab.ui.container.Panel
        UIAxes_Heatmap                 matlab.ui.control.UIAxes
        ChestXRaywithMaskPanel         matlab.ui.container.Panel
        UIAxes_Mask                    matlab.ui.control.UIAxes
        AdjustmetPanel                 matlab.ui.container.Panel
        RunButton                      matlab.ui.control.StateButton
        TopProbabilityEditField        matlab.ui.control.EditField
        TopProbabilityLabel            matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        GuidelinesPanel                matlab.ui.container.Panel
        TextArea                       matlab.ui.control.TextArea
    end

    properties (Access = private)
        check_loading = 0; % Check loading is done
        check_image % Check loading is image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadingChestXRayDICOMButton
        function LoadingChestXRayDICOMButtonPushed(app, event)
            app.FilenameEditField.Value = '';
            cla(app.UIAxes)
            app.PneumoniaProbabilityEditField.Value = '';
            app.ClassificationResultEditField.Value = '';
            cla(app.UIAxes_Heatmap)
            cla(app.UIAxes_Mask)
            [filename,filepath] = uigetfile('*.dcm','Please select a dicom file to import.');
            if filename
                app.FilenameEditField.Value = [filepath,filename];
                info = dicominfo([filepath,filename]);
                dcmimg = dicomread(info);
                imshow(dcmimg,[],'parent',app.UIAxes)
                app.UIAxes.Title.String = 'Chest X-Ray';
                app.UIAxes.XLabel.String = 'Right - Left';
                app.UIAxes.YLabel.String = 'Lower - Upper';
                app.check_image = 0;
                app.check_loading = 1;
                msgbox('Dicom File importing is done.')
            end
        end

        % Button pushed function: LoadingChestXRayPictureButton
        function LoadingChestXRayPictureButtonPushed(app, event)
            app.FilenameEditField.Value = '';
            cla(app.UIAxes)
            app.PneumoniaProbabilityEditField.Value = '';
            app.ClassificationResultEditField.Value = '';
            cla(app.UIAxes_Heatmap)
            cla(app.UIAxes_Mask)
            [filename,filepath] = uigetfile({'*.jpg;*.bmp;*.png;*.jpeg;*.tiff'},'Please select a image to import.');
            if filename
                app.FilenameEditField.Value = [filepath,filename];
                img = imread([filepath,filename]);
                imshow(img,'parent',app.UIAxes)
                app.UIAxes.Title.String = 'Chest X-Ray';
                app.UIAxes.XLabel.String = 'Right - Left';
                app.UIAxes.YLabel.String = 'Lower - Upper';
                app.check_image = 1;
                app.check_loading = 1;
                msgbox('Image importing is done.')
            end
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            if app.check_loading == 0
                errordlg('Please load image first!')
                return
            end
            modelname = app.ModelDropDown.Value;
            if contains(modelname,'Ensemble')
                cla(app.UIAxes_Heatmap)
                cla(app.UIAxes_Mask)
                app.TopProbabilityEditField.Value = 'No support!';
                if app.check_image == 0
                    info = dicominfo(app.FilenameEditField.Value);
                    dcmimg = dicomread(info);
                    img = uint8(255 * mat2gray(dcmimg)); %Convert to uint8 format
                elseif app.check_image == 1
                    img = imread(app.FilenameEditField.Value);
                end
                % === model checking
                mat_dir = dir('Info/*.mat');
                % === model importing and ensembling
                PredProb = zeros(1,2);
                for idx_model = 1:length(mat_dir)
                    load(fullfile(mat_dir(idx_model).folder,mat_dir(idx_model).name))
                    img = imresize(img,net.Layers(1).InputSize(1:2));
                    img = uint8(img);
                    img_3d = uint8(zeros(net.Layers(1).InputSize));
                    for idx_channel = 1:3
                        img_3d(:,:,idx_channel) = img;
                    end
                    % === probability
                    TestPredProb = predict(net,img_3d);
                    % === Ensemble
                    PredProb = PredProb + (1/length(mat_dir))*TestPredProb;
                end
                [~,loc] = max(PredProb);
                if loc == 2
                    TestPred = 'Pneumonia';
                elseif loc == 1
                    TestPred = 'Control';
                else
                    error('No this Type')
                end
                app.PneumoniaProbabilityEditField.Value = sprintf('%.4f',PredProb(2));
                app.ClassificationResultEditField.Value = string(TestPred);
            else
                load(sprintf('Info/net_%s.mat',modelname));
                if app.check_image == 0
                    info = dicominfo(app.FilenameEditField.Value);
                    dcmimg = dicomread(info);
                    img = uint8(255 * mat2gray(dcmimg)); %Convert to uint8 format
                elseif app.check_image == 1
                    img = imread(app.FilenameEditField.Value);
                end
                img = imresize(img,net.Layers(1).InputSize(1:2));
                img = uint8(img);
                img_3d = uint8(zeros(net.Layers(1).InputSize));
                for idx = 1:3
                    img_3d(:,:,idx) = img;
                end
                % === probability
                TestPredProb = predict(net,img_3d);
                app.PneumoniaProbabilityEditField.Value = sprintf('%.4f',TestPredProb(2));
                % === classification
                TestPred = classify(net,img_3d);
                app.ClassificationResultEditField.Value = string(TestPred);
                % === Grad-CAM
                map = gradCAM(net,img_3d,TestPred);
                imshow(img_3d,'parent',app.UIAxes_Heatmap)
                hold(app.UIAxes_Heatmap,'on')
                imagesc(map,'AlphaData',0.2,'parent',app.UIAxes_Heatmap)
                colormap(app.UIAxes_Heatmap, jet);
                colorbar(app.UIAxes_Heatmap);
                hold(app.UIAxes_Heatmap,'off')
                app.UIAxes_Heatmap.Title.String = 'Grad-CAM of Chest X-Ray';
                app.UIAxes_Heatmap.XLabel.String = 'Right - Left';
                app.UIAxes_Heatmap.YLabel.String = 'Lower - Upper';
                % === Mask
                app.TopProbabilityEditField.Value = string(10);
                sort_mat = sort(map(:), 'descend');
                cut_prob = 0.1;
                cut_pt = fix(cut_prob*length(sort_mat));
                mask = map > sort_mat(cut_pt);
                img_roi = uint8(mask).*img;
                imshow(img_roi,'parent',app.UIAxes_Mask)
                app.UIAxes_Mask.Title.String = 'Mask View of Chest X-Ray';
                app.UIAxes_Mask.XLabel.String = 'Right - Left';
                app.UIAxes_Mask.YLabel.String = 'Lower - Upper';
            end
        end

        % Button pushed function: FeedbackButton
        function FeedbackButtonPushed(app, event)
            web('https://forms.gle/CYeS6QzReJ7N3dAQ8')
        end

        % Button pushed function: ClearandCloseAllButton
        function ClearandCloseAllButtonPushed(app, event)
            app.FilenameEditField.Value = '';
            cla(app.UIAxes)
            app.PneumoniaProbabilityEditField.Value = '';
            app.ClassificationResultEditField.Value = '';
            cla(app.UIAxes_Heatmap)
            cla(app.UIAxes_Mask)
            app.TopProbabilityEditField.Value = '';
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.delete
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            valueSlider = app.Slider.Value;
            app.TopProbabilityEditField.Value = string(valueSlider);
        end

        % Button down function: UIAxes_Mask
        function UIAxes_MaskButtonDown(app, event)

        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            if app.check_loading == 0
                errordlg('Please load image first!')
                return
            end
            modelname = app.ModelDropDown.Value;
            if contains(modelname,'Ensemble')
                cla(app.UIAxes_Heatmap)
                cla(app.UIAxes_Mask)
                app.TopProbabilityEditField.Value = 'No support!';
                errordlg('No support when calling model of "Ensemble".')
            else
                load(sprintf('Info/net_%s.mat',modelname));
                if app.check_image == 0
                    info = dicominfo(app.FilenameEditField.Value);
                    dcmimg = dicomread(info);
                    img = uint8(255 * mat2gray(dcmimg)); %Convert to uint8 format
                elseif app.check_image == 1
                    img = imread(app.FilenameEditField.Value);
                end
                img = imresize(img,net.Layers(1).InputSize(1:2));
                img = uint8(img);
                img_3d = uint8(zeros(net.Layers(1).InputSize));
                for idx = 1:3
                    img_3d(:,:,idx) = img;
                end
                TestPred = classify(net,img_3d);
                map = gradCAM(net,img_3d,TestPred);
                % === Mask
                sort_mat = sort(map(:), 'descend');
                cut_prob = 0.01*app.Slider.Value;
                cut_pt = fix(cut_prob*length(sort_mat));
                mask = map > sort_mat(cut_pt);
                img_roi = uint8(mask).*img;
                imshow(img_roi,'parent',app.UIAxes_Mask)
                app.UIAxes_Mask.Title.String = 'Mask View of Chest X-Ray';
                app.UIAxes_Mask.XLabel.String = 'Right - Left';
                app.UIAxes_Mask.YLabel.String = 'Lower - Upper';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.LoadingPanel = uipanel(app.AnalysisTab);
            app.LoadingPanel.Title = 'Loading';
            app.LoadingChestXRayDICOMButton = uibutton(app.LoadingPanel, 'push');
            app.LoadingChestXRayDICOMButton.ButtonPushedFcn = createCallbackFcn(app, @LoadingChestXRayDICOMButtonPushed, true);
            app.LoadingChestXRayDICOMButton.Text = 'Loading Chest X-Ray DICOM';
            app.LoadingChestXRayPictureButton = uibutton(app.LoadingPanel, 'push');
            app.LoadingChestXRayPictureButton.ButtonPushedFcn = createCallbackFcn(app, @LoadingChestXRayPictureButtonPushed, true);
            app.LoadingChestXRayPictureButton.Text = 'Loading Chest X-Ray Picture';
            app.ChestXRayViewerPanel = uipanel(app.AnalysisTab);
            app.ChestXRayViewerPanel.Title = 'Chest X-Ray Viewer';
            app.UIAxes = uiaxes(app.ChestXRayViewerPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = 0;
            app.FilenameLabel = uilabel(app.ChestXRayViewerPanel);
            app.FilenameLabel.Text = 'Filename:';
            app.FilenameEditField = uieditfield(app.ChestXRayViewerPanel, 'text');
            app.OtherManagementPanel = uipanel(app.AnalysisTab);
            app.OtherManagementPanel.Title = 'Other Management';
            app.ClearandCloseAllButton = uibutton(app.OtherManagementPanel, 'push');
            app.ClearandCloseAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearandCloseAllButtonPushed, true);
            app.ClearandCloseAllButton.Text = 'Clear and Close All';
            app.ExitButton = uibutton(app.OtherManagementPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.PredictingPanel = uipanel(app.AnalysisTab);
            app.PredictingPanel.Title = 'Predicting';
            app.PredictButton = uibutton(app.PredictingPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.ModelDropDownLabel = uilabel(app.PredictingPanel);
            app.ModelDropDownLabel.Text = 'Model: ';
            app.ModelDropDown = uidropdown(app.PredictingPanel);
            app.ModelDropDown.Items = {'AlexNet', 'DarkNet-53', 'DenseNet', 'EfficientNetB0', 'GoogLeNet', 'Inception-ResNet-v2', 'Inception-v3', 'MobileNet-v2', 'NASNet-Mobile', 'ResNet-101', 'ResNet-18', 'ResNet-50', 'ShuffleNet', 'Xception', 'Ensemble'};
            app.ModelDropDown.Value = 'ResNet-18';
            app.PneumoniaProbabilityEditFieldLabel = uilabel(app.PredictingPanel);
            app.PneumoniaProbabilityEditFieldLabel.Text = 'Pneumonia Probability:';
            app.PneumoniaProbabilityEditField = uieditfield(app.PredictingPanel, 'text');
            app.ClassificationResultEditFieldLabel = uilabel(app.PredictingPanel);
            app.ClassificationResultEditFieldLabel.Text = 'Classification Result:';
            app.ClassificationResultEditField = uieditfield(app.PredictingPanel, 'text');
            app.FeedbackButton = uibutton(app.PredictingPanel, 'push');
            app.FeedbackButton.ButtonPushedFcn = createCallbackFcn(app, @FeedbackButtonPushed, true);
            app.FeedbackButton.Text = 'Feedback';
            app.HeatMapGradCAMTab = uitab(app.TabGroup);
            app.HeatMapGradCAMTab.Title = 'Heat Map (Grad-CAM)';
            app.GuidelinesPanel = uipanel(app.HeatMapGradCAMTab);
            app.GuidelinesPanel.Title = 'Guidelines';
            app.TextArea = uitextarea(app.GuidelinesPanel);
            app.TextArea.Value = {'You could move the slider to visualize the top probability you select to show the mask and do the segmentation (Run button).'};
            app.AdjustmetPanel = uipanel(app.HeatMapGradCAMTab);
            app.AdjustmetPanel.Title = 'Adjustmet';
            app.SliderLabel = uilabel(app.AdjustmetPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.AdjustmetPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.Slider.Value = 10;
            app.TopProbabilityLabel = uilabel(app.AdjustmetPanel);
            app.TopProbabilityLabel.Text = 'Top Probability (%):';
            app.TopProbabilityEditField = uieditfield(app.AdjustmetPanel, 'text');
            app.RunButton = uibutton(app.AdjustmetPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.ChestXRaywithMaskPanel = uipanel(app.HeatMapGradCAMTab);
            app.ChestXRaywithMaskPanel.Title = 'Chest X-Ray with Mask';
            app.UIAxes_Mask = uiaxes(app.ChestXRaywithMaskPanel);
            title(app.UIAxes_Mask, 'Title')
            xlabel(app.UIAxes_Mask, 'X')
            ylabel(app.UIAxes_Mask, 'Y')
            app.UIAxes_Mask.XTick = [];
            app.UIAxes_Mask.YTick = [];
            app.UIAxes_Mask.ZTick = 0;
            app.UIAxes_Mask.ButtonDownFcn = createCallbackFcn(app, @UIAxes_MaskButtonDown, true);
            app.ChestXRaywithGradCAMPanel = uipanel(app.HeatMapGradCAMTab);
            app.ChestXRaywithGradCAMPanel.Title = 'Chest X-Ray with Grad-CAM';
            app.UIAxes_Heatmap = uiaxes(app.ChestXRaywithGradCAMPanel);
            title(app.UIAxes_Heatmap, 'Title')
            xlabel(app.UIAxes_Heatmap, 'X')
            ylabel(app.UIAxes_Heatmap, 'Y')
            app.UIAxes_Heatmap.XTick = [];
            app.UIAxes_Heatmap.YTick = [];
            app.UIAxes_Heatmap.ZTick = 0;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppVisual

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end